classdef analysisScreen2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        AxisControlsPanel               matlab.ui.container.Panel
        ABDXMinEditFieldLabel           matlab.ui.control.Label
        ABDXMinEditField                matlab.ui.control.NumericEditField
        ABDXMaxEditFieldLabel           matlab.ui.control.Label
        ABDXMaxEditField                matlab.ui.control.NumericEditField
        CHXMinEditFieldLabel            matlab.ui.control.Label
        CHXMinEditField                 matlab.ui.control.NumericEditField
        CHXMaxEditFieldLabel            matlab.ui.control.Label
        CHXMaxEditField                 matlab.ui.control.NumericEditField
        ResetAxesButton                 matlab.ui.control.Button
        PerBreathAnalysisPanel          matlab.ui.container.Panel
        ExcludeBreathButton             matlab.ui.control.Button
        PALabel                         matlab.ui.control.Label
        TPEFTELabel                     matlab.ui.control.Label
        RCiLabel                        matlab.ui.control.Label
        ViewKMPlotButton                matlab.ui.control.Button
        DurationLabel                   matlab.ui.control.Label
        ResetAnalysisButton             matlab.ui.control.Button
        ViewAnalysisDetailsButton       matlab.ui.control.Button
        ExportAnalysisButton            matlab.ui.control.Button
        SummaryofRecordingPanel         matlab.ui.container.Panel
        MeanPALabel                     matlab.ui.control.Label
        TotalNumberofBreathsLabel       matlab.ui.control.Label
        MeanTPEFTELabel                 matlab.ui.control.Label
        MeanRCiLabel                    matlab.ui.control.Label
        NumberofAnalyzableBreathsLabel  matlab.ui.control.Label
        MeanDurationLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GoToPanel                       matlab.ui.container.Panel
        BreathEditFieldLabel            matlab.ui.control.Label
        BreathEditField                 matlab.ui.control.NumericEditField
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        PreviousNextAnalyzableBreathLabel  matlab.ui.control.Label
        ExportRawDataButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        %OriginalABData % may want to make these constants
        %OriginalCHData
        CHData
        Metadata
        
        CHBreathLim
        ABBreathLim
        
        CurrentCHPlot
        CurrentABPlot
        
        RemovedBreaths = [];
        
        DetailsMessage
    end
    
    methods (Access = private)
        
        function plotBreaths(app)
            app.plotCHbreaths();
            app.plotABbreaths();
            app.resetAxes();
        end
            
        function plotCHbreaths(app)

            hold(app.UIAxes, 'on');
            
            waveform = app.CHData.getFiltData();
            timeVector = (1:length(waveform))./app.Metadata.SamplingFrequency;
            waveform = downsample(waveform, 20);
            timeVector = downsample(timeVector, 20);
            
            plot(app.UIAxes, timeVector, waveform, 'Color', '#A9A9A9');
            
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            for i = 1:length(analyzableBreaths)
                CHbreath = app.CHData.getBreath(analyzableBreaths(i));
                CHdata = CHbreath.getData();
                indices = CHbreath.getIndices();
                samplesPerSec = 10;
                nthSample = floor(app.Metadata.SamplingFrequency/samplesPerSec);
                
                Ydata = downsample(CHdata,nthSample);
                Xdata = downsample(indices/app.Metadata.SamplingFrequency, nthSample);
              
                plot(app.UIAxes, Xdata, Ydata, ...
                    'Color', '#00ff00', 'LineWidth', 2); 
                
                label = int2str(i);
                xline(app.UIAxes, Xdata(1), ':', label);
            end
            

            hold(app.UIAxes, 'off');
        end
        
        function plotABbreaths(app)

            hold(app.UIAxes2, 'on');
            
            waveform = app.CHData.getAssociatedData();
            timeVector = (1:length(waveform))./app.Metadata.SamplingFrequency;
            waveform = downsample(waveform, 20);
            timeVector = downsample(timeVector, 20);
            
            plot(app.UIAxes2, timeVector, waveform, 'Color', '#A9A9A9');
            
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            for i = 1:length(analyzableBreaths)
                CHbreath = app.CHData.getBreath(analyzableBreaths(i));
                ABdata = CHbreath.getAssociatedData();
                indices = CHbreath.getIndices();
                samplesPerSec = 10;
                nthSample = floor(app.Metadata.SamplingFrequency/samplesPerSec);
                
                Ydata = downsample(ABdata,nthSample);
                Xdata = downsample(indices/app.Metadata.SamplingFrequency, nthSample);
              
                plot(app.UIAxes2, Xdata, Ydata, ...
                    'Color', "#00ff00", 'LineWidth', 2);
                
                label = int2str(i);
                xline(app.UIAxes2, Xdata(1), ':', label);
            end
            
            hold(app.UIAxes2, 'off');
            
            
        end
        
        function setABAxes(app, xmin, xmax)
            app.UIAxes2.XLim = [xmin, xmax];
            app.ABDXMaxEditField.Value = xmax;
            app.ABDXMinEditField.Value = xmin;
        
        end
        
        function setCHAxes(app, xmin, xmax)
            app.UIAxes.XLim = [xmin, xmax];
            app.CHXMaxEditField.Value = xmax;
            app.CHXMinEditField.Value = xmin;

        end
        
        function resetAxes(app)
             app.UIAxes.XLim = [0, 30];
             app.UIAxes2.XLim = [0, 30];
        end
        
        function updateSummary(app)
            inds = app.CHData.getIndicesAnalyzable();
            if ~isempty(app.RemovedBreaths)
                inds(app.RemovedBreaths) = [];
            end
            
            app.TotalNumberofBreathsLabel.Text = ['Total Number of Breaths: ' num2str(app.CHData.getNumBreaths())];
            app.NumberofAnalyzableBreathsLabel.Text = ['Number of Analyzable Breaths: ' num2str(length(inds))...
                ' (' num2str(length(inds)/app.CHData.getNumBreaths()*100, 2) '%)'];  
            
            [meanPA, sdPA] = app.CHData.calculateMeanPA(inds);
            [meanTTE, sdTTE] = app.CHData.calculateMeanTPEFTE(inds);
            [meanRC, sdRC] = app.CHData.calculateMeanRCI(inds);
            [meanRR, sdRR] = app.CHData.calculateMeanRespiratoryRate(inds);
            
            app.MeanPALabel.Text = ['Mean PA (deg): ' num2str(meanPA, 4) ' (SD: ' num2str(sdPA,4) ')'];
            app.MeanTPEFTELabel.Text = ['Mean Tpef/TE: ' num2str(meanTTE, 3) ' (SD: ' num2str(sdTTE,3) ')'];
            app.MeanRCiLabel.Text = ['Mean %RCi: ' num2str(meanRC, 3) ' (SD: ' num2str(sdRC,2) ')'];
            app.MeanDurationLabel.Text = ['Mean Respiratory Rate (bpm): ' num2str(meanRR,3) ' (SD: ' num2str(sdRR,2) ')'];
                      
        end
        
        function adjustComponents(app)
            app.BreathEditField.Limits = [1, length(app.CHData.getIndicesAnalyzable())];
            
            
            app.CHXMinEditField.Value = 0;
            app.CHXMaxEditField.Value = 30;
            app.ABDXMinEditField.Value = 0;
            app.ABDXMaxEditField.Value = 30;
        end
        
        
        function updatePerBreathAnalysis(app, breathNum)
            breath = app.CHData.getBreath(breathNum);
            if breath.getAnalyzable()
                app.PALabel.Text = ['PA (deg): ' num2str(breath.getPhaseAngle(), 4)];
                app.TPEFTELabel.Text = ['Tpef/TE: ' num2str(breath.getTPEFTE(), 3)];
                app.RCiLabel.Text = ['%RCi: ' num2str(breath.getRCI(), 3)];
                app.DurationLabel.Text = ['Duration (sec): ' num2str(breath.getDuration(),3)];
                
            else
                warning('Non-analyzable breath index passed to app.updatePerBreathAnalysis')
                app.PALabel.Text = ['PA (deg): NA'];
                app.TPEFTELabel.Text = ['Tpef/TE: NA'];
                app.RCiLabel.Text = ['%RCi: NA'];
                app.DurationLabel.Text = ['Duration (sec): NA'];
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CHData)


            app.CHData = CHData;

            app.Metadata = CHData.getMetadata();

            app.adjustComponents();
            
            app.plotBreaths();
            app.updateSummary();
        end

        % Button pushed function: ExportAnalysisButton
        function ExportAnalysisButtonPushed(app, event)
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            for i = 1:length(app.RemovedBreaths)
                app.CHData.overrideAnalyzable(analyzableBreaths(app.RemovedBreaths(i)), false);
            end
            fileMaker = FileIOHandler(app.CHData);
            fileMaker.exportAnalysis(app.UIFigure);
            
        end

        % Button pushed function: ViewAnalysisDetailsButton
        function ViewAnalysisDetailsButtonPushed(app, event)
            
            uialert(app.UIFigure,message,'Analysis Details','Icon','');
        end

        % Value changed function: BreathEditField
        function BreathEditFieldValueChanged(app, event)
            value = app.BreathEditField.Value;
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            nthGoodBreath = analyzableBreaths(value);
            CHbreath = app.CHData.getBreath(nthGoodBreath);
            sInd = CHbreath.getStartIndex()/app.Metadata.SamplingFrequency;
            app.setCHAxes(sInd, sInd + 30);
            app.setABAxes(sInd, sInd + 30);
            
            app.updatePerBreathAnalysis(analyzableBreaths(value));
        end

        % Value changed function: ABDXMinEditField
        function ABDXMinEditFieldValueChanged(app, event)
            value = app.ABDXMinEditField.Value;
            
            if app.ABDXMaxEditField.Value > value
                app.setABAxes(value, app.ABDXMaxEditField.Value);
            else
                app.setABAxes(value, value+30);
            end
            
        end

        % Value changed function: ABDXMaxEditField
        function ABDXMaxEditFieldValueChanged(app, event)
            value = app.ABDXMaxEditField.Value;
            
            if app.ABDXMinEditField.Value < value
                app.setABAxes(app.ABDXMinEditField.Value, value);
            else
                app.setABAxes(value-30, value);
            end
            
        end

        % Value changed function: CHXMinEditField
        function CHXMinEditFieldValueChanged(app, event)
            value = app.CHXMinEditField.Value;
            
            if app.CHXMaxEditField.Value > value
                app.setCHAxes(value, app.CHXMaxEditField.Value);
            else
                app.setCHAxes(value, value+30);
            end
            
        end

        % Value changed function: CHXMaxEditField
        function CHXMaxEditFieldValueChanged(app, event)
            value = app.CHXMaxEditField.Value;
            if app.CHXMinEditField.Value < value
                app.setCHAxes(app.CHXMinEditField.Value, value);
            else
                app.setCHAxes(value-30, value);
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % FORWARD BUTTON
            [xlimCH] = xlim(app.UIAxes);
            [xlimAB] = xlim(app.UIAxes2);
            minX = max(xlimCH(1), xlimAB(1))*app.Metadata.SamplingFrequency; % find the greater of the two xlim minimums
            analyzableInds = app.CHData.getIndicesAnalyzable();
            
            startIndex = 0;
            count = 0;
            while startIndex <= minX
                count = count+1;
                nextBreath = app.CHData.getBreath(analyzableInds(count));
                startIndex = nextBreath.getStartIndex();
            end
            startIndex = startIndex/app.Metadata.SamplingFrequency;
        
            app.BreathEditField.Value = count;
            
            app.setCHAxes(startIndex, startIndex + 30);
            app.setABAxes(startIndex, startIndex + 30);
            
            app.updatePerBreathAnalysis(analyzableInds(count));
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % BACK BUTTON
            [xlimCH] = xlim(app.UIAxes);
            [xlimAB] = xlim(app.UIAxes2);
            minX = max(xlimCH(1), xlimAB(1))*app.Metadata.SamplingFrequency;
            analyzableInds = app.CHData.getIndicesAnalyzable();
            
            startIndex = 0;
            count = 0;
            while startIndex < minX
                count = count+1;
                nextBreath = app.CHData.getBreath(analyzableInds(count));   % ABD LEAD RELIANCE
                startIndex = nextBreath.getStartIndex();
            end
            prevBreath = app.CHData.getBreath(analyzableInds(count -1));
            startIndex = prevBreath.getStartIndex()/app.Metadata.SamplingFrequency;
            
            app.BreathEditField.Value = count-1;

            app.setCHAxes(startIndex, startIndex + 30);
            app.setABAxes(startIndex, startIndex + 30);
            
            app.updatePerBreathAnalysis(analyzableInds(count-1));
        end

        % Button pushed function: ResetAxesButton
        function ResetAxesButtonPushed(app, event)
            app.setCHAxes(0,30);
            app.setABAxes(0,30);
            app.BreathEditField.Value = 1;
        end

        % Button pushed function: ViewKMPlotButton
        function ViewKMPlotButtonPushed(app, event)
            value = app.BreathEditField.Value;
            analyzableBreaths = app.CHData.getIndicesAnalyzable();
            CHnum = analyzableBreaths(value);
            CHbreath = app.CHData.getBreath(CHnum);
            CHdata = CHbreath.getData();
            ABdata = CHbreath.getAssociatedData();
            f = gcf;
            set(f, 'NumberTitle', 'off', ...
            'Name', ['Konno-Mead Plot: Breath ', num2str(analyzableBreaths(value))]);
            plot(ABdata, CHdata);
            title(['Konno-Mead Plot, Breath ' num2str(value)]);
            xlabel('AB Data');
            ylabel('CH Data');
        end

        % Button pushed function: ExcludeBreathButton
        function ExcludeBreathButtonPushed(app, event)
            value = app.BreathEditField.Value;
            if any(app.RemovedBreaths == value)
                uialert(app.UIFigure, 'Breath already excluded!','Warning',...
                'Icon','warning');
            else
                app.RemovedBreaths = [app.RemovedBreaths; value];
                analyzableBreaths = app.CHData.getIndicesAnalyzable();
                breath = app.CHData.getBreath(analyzableBreaths(value));
                indices = breath.getIndices()/app.Metadata.SamplingFrequency;
                
                
                hold(app.UIAxes, 'on');
                hold(app.UIAxes2, 'on');
                plot(app.UIAxes, indices, breath.getData(), 'Color', '#ff0000', 'LineWidth', 2);
                plot(app.UIAxes2, indices, breath.getAssociatedData(), 'Color', '#ff0000', 'LineWidth', 2);
                
                app.updateSummary();
                
                
            end
            
        end

        % Button pushed function: ResetAnalysisButton
        function ResetAnalysisButtonPushed(app, event)
            app.RemovedBreaths = [];
            app.plotBreaths();
            app.BreathEditField.Value = 1;
            app.updateSummary();
        end

        % Button pushed function: ExportRawDataButton
        function ExportRawDataButtonPushed(app, event)
            fileMaker = FileIOHandler(app.CHData);
            fileMaker.saveRawDataFile(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AxisControlsPanel = uipanel(app.GridLayout);
            app.AxisControlsPanel.Title = 'Axis Controls';
            app.ABDXMinEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.ABDXMinEditFieldLabel.Text = 'ABD X Min';
            app.ABDXMinEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.ABDXMinEditField.ValueDisplayFormat = '%.1f';
            app.ABDXMinEditField.ValueChangedFcn = createCallbackFcn(app, @ABDXMinEditFieldValueChanged, true);
            app.ABDXMaxEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.ABDXMaxEditFieldLabel.Text = 'ABD X Max';
            app.ABDXMaxEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.ABDXMaxEditField.ValueDisplayFormat = '%.1f';
            app.ABDXMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ABDXMaxEditFieldValueChanged, true);
            app.CHXMinEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.CHXMinEditFieldLabel.Text = 'CH X Min';
            app.CHXMinEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.CHXMinEditField.ValueDisplayFormat = '%.1f';
            app.CHXMinEditField.ValueChangedFcn = createCallbackFcn(app, @CHXMinEditFieldValueChanged, true);
            app.CHXMaxEditFieldLabel = uilabel(app.AxisControlsPanel);
            app.CHXMaxEditFieldLabel.Text = 'CH X Max';
            app.CHXMaxEditField = uieditfield(app.AxisControlsPanel, 'numeric');
            app.CHXMaxEditField.ValueDisplayFormat = '%.1f';
            app.CHXMaxEditField.ValueChangedFcn = createCallbackFcn(app, @CHXMaxEditFieldValueChanged, true);
            app.ResetAxesButton = uibutton(app.AxisControlsPanel, 'push');
            app.ResetAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAxesButtonPushed, true);
            app.ResetAxesButton.Text = 'Reset Axes';
            app.PerBreathAnalysisPanel = uipanel(app.GridLayout);
            app.PerBreathAnalysisPanel.Title = 'Per-Breath Analysis';
            app.ExcludeBreathButton = uibutton(app.PerBreathAnalysisPanel, 'push');
            app.ExcludeBreathButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeBreathButtonPushed, true);
            app.ExcludeBreathButton.Text = 'Exclude Breath';
            app.PALabel = uilabel(app.PerBreathAnalysisPanel);
            app.PALabel.Text = 'PA:';
            app.TPEFTELabel = uilabel(app.PerBreathAnalysisPanel);
            app.TPEFTELabel.Text = 'TPEF/TE:';
            app.RCiLabel = uilabel(app.PerBreathAnalysisPanel);
            app.RCiLabel.Text = '%RCi: ';
            app.ViewKMPlotButton = uibutton(app.PerBreathAnalysisPanel, 'push');
            app.ViewKMPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ViewKMPlotButtonPushed, true);
            app.ViewKMPlotButton.Text = 'View KM Plot';
            app.DurationLabel = uilabel(app.PerBreathAnalysisPanel);
            app.DurationLabel.Text = 'Duration:';
            app.ResetAnalysisButton = uibutton(app.GridLayout, 'push');
            app.ResetAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAnalysisButtonPushed, true);
            app.ResetAnalysisButton.Text = 'Reset Analysis';
            app.ViewAnalysisDetailsButton = uibutton(app.GridLayout, 'push');
            app.ViewAnalysisDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewAnalysisDetailsButtonPushed, true);
            app.ViewAnalysisDetailsButton.Text = 'View Analysis Details';
            app.ExportAnalysisButton = uibutton(app.GridLayout, 'push');
            app.ExportAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAnalysisButtonPushed, true);
            app.ExportAnalysisButton.Text = 'Export Analysis';
            app.SummaryofRecordingPanel = uipanel(app.GridLayout);
            app.SummaryofRecordingPanel.Title = 'Summary of Recording';
            app.MeanPALabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanPALabel.Text = 'Mean PA:';
            app.TotalNumberofBreathsLabel = uilabel(app.SummaryofRecordingPanel);
            app.TotalNumberofBreathsLabel.Text = 'Total Number of Breaths:';
            app.MeanTPEFTELabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanTPEFTELabel.Text = 'Mean TPEF/TE:';
            app.MeanRCiLabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanRCiLabel.Text = 'Mean %RCi: ';
            app.NumberofAnalyzableBreathsLabel = uilabel(app.SummaryofRecordingPanel);
            app.NumberofAnalyzableBreathsLabel.Text = 'Number of Analyzable Breaths:';
            app.MeanDurationLabel = uilabel(app.SummaryofRecordingPanel);
            app.MeanDurationLabel.Text = 'Mean Duration:';
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'CH Data')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'CH Signal (mV)')
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'AB Data')
            xlabel(app.UIAxes2, 'Time (sec)')
            ylabel(app.UIAxes2, 'AB Signal (mV)')
            app.GoToPanel = uipanel(app.GridLayout);
            app.GoToPanel.Title = 'Go To';
            app.BreathEditFieldLabel = uilabel(app.GoToPanel);
            app.BreathEditFieldLabel.Text = 'Breath';
            app.BreathEditField = uieditfield(app.GoToPanel, 'numeric');
            app.BreathEditField.Limits = [0 1];
            app.BreathEditField.ValueChangedFcn = createCallbackFcn(app, @BreathEditFieldValueChanged, true);
            app.Button = uibutton(app.GoToPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.GoToPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.PreviousNextAnalyzableBreathLabel = uilabel(app.GoToPanel);
            app.PreviousNextAnalyzableBreathLabel.Text = {'Previous/Next '; 'Analyzable Breath'};
            app.ExportRawDataButton = uibutton(app.GridLayout, 'push');
            app.ExportRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRawDataButtonPushed, true);
            app.ExportRawDataButton.Text = 'Export Raw Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysisScreen2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end